classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ConvolutionTab                matlab.ui.container.Tab
        ExecuteConvolutionButton      matlab.ui.control.Button
        LoadImageConvolutionButton    matlab.ui.control.Button
        InputKernelConvolutionLabel   matlab.ui.control.Label
        SpinnerConvolution            matlab.ui.control.Spinner
        MaskSizenxnSpinnerLabel       matlab.ui.control.Label
        OutputImageConvolutionLabel   matlab.ui.control.Label
        InputImageConvolutionLabel    matlab.ui.control.Label
        ImageOutputConvolution        matlab.ui.control.Image
        UITableConvolution            matlab.ui.control.Table
        ImageInputConvolution         matlab.ui.control.Image
        ImageSmoothingSpatialTab      matlab.ui.container.Tab
        SigmaValueEditFieldSmoothing  matlab.ui.control.NumericEditField
        SigmaValueEditFieldLabel      matlab.ui.control.Label
        MaskSizeEditFieldSmoothing    matlab.ui.control.NumericEditField
        MaskSizeEditFieldLabel        matlab.ui.control.Label
        FilterTypeDropDownSmoothing   matlab.ui.control.DropDown
        FilterTypeDropDownLabel       matlab.ui.control.Label
        ExecuteSmoothingButton        matlab.ui.control.Button
        LoadImageSmoothingButton      matlab.ui.control.Button
        OutputImageSmoothingLabel     matlab.ui.control.Label
        InputImageSmoothingLabel      matlab.ui.control.Label
        ImageOutputSmoothing          matlab.ui.control.Image
        ImageInputSmoothing           matlab.ui.control.Image
        ImageSmoothingFrequencyTab    matlab.ui.container.Tab
        FilterTypeDropDownLP          matlab.ui.control.DropDown
        FilterTypeDropDownLabel_2     matlab.ui.control.Label
        OrderEditFieldLP              matlab.ui.control.NumericEditField
        OrderLabel                    matlab.ui.control.Label
        CutoffValueEditFieldLP        matlab.ui.control.NumericEditField
        CutoffFrequencyLabel          matlab.ui.control.Label
        ExecuteLPButton               matlab.ui.control.Button
        LoadImageLPButton             matlab.ui.control.Button
        OutputImageLPLabel            matlab.ui.control.Label
        InputImageLPLabel             matlab.ui.control.Label
        ImageOutputLP                 matlab.ui.control.Image
        ImageInputLP                  matlab.ui.control.Image
        ImageSharpeningFrequencyTab   matlab.ui.container.Tab
        FilterTypeDropDownHP          matlab.ui.control.DropDown
        FilterTypeDropDownLabel_3     matlab.ui.control.Label
        OrderEditFieldHP              matlab.ui.control.NumericEditField
        OrderLabel_2                  matlab.ui.control.Label
        CutoffValueEditFieldHP        matlab.ui.control.NumericEditField
        CutoffFrequencyLabel_2        matlab.ui.control.Label
        ExecuteHPButton               matlab.ui.control.Button
        LoadImageHPButton             matlab.ui.control.Button
        OutputImageHPLabel            matlab.ui.control.Label
        InputImageHPLabel             matlab.ui.control.Label
        ImageOutputHP                 matlab.ui.control.Image
        ImageInputHP                  matlab.ui.control.Image
        ImageBrighteningTab           matlab.ui.container.Tab
        SaltPepperRemovalTab          matlab.ui.container.Tab
        PeriodicNoiseRemovalTab       matlab.ui.container.Tab
        WienerMotionBlurTab           matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Get the screen size
            screenSize = get(groot, 'ScreenSize');
            
            % Set UIFigure position to fullscreen
            app.UIFigure.Position = [screenSize(1) screenSize(2) screenSize(3) screenSize(4)];

            app.UITableConvolution.Data = [0 0 0; 0 0 0; 0 0 0];
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            % Get current size of the UIFigure
            figWidth = app.UIFigure.Position(3);
            figHeight = app.UIFigure.Position(4);
            
            % Update the TabGroup to match UIFigure's size
            app.TabGroup.Position = [1, 1, figWidth, figHeight];
        end

        % Button pushed function: LoadImageConvolutionButton
        function LoadImageConvolutionButtonPushed(app, event)
            % Open file explorer to select an image
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tif;*.tiff', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp, *.tif, *.tiff)'});
            if isequal(file, 0)
                return;  % User cancelled the file selection
            else
                % Load the selected image
                img_path = fullfile(path, file);
                img = imread(img_path);  % Read the image
                [~, name, ext] = fileparts(img_path);  % Get the file extension
                
                % Convert unsupported formats (like bmp or tiff) to png
                if strcmpi(ext, '.bmp') || strcmpi(ext, '.tiff') || strcmp(ext, '.tif')
                    % Save the converted image to a valid directory (using tempdir)
                    new_img_path = fullfile(tempdir, [name, '.png']);  % Use tempdir for temporary PNG file
                    imwrite(img, new_img_path);  % Convert and save as PNG
                    img_path = new_img_path;  % Update the path to the new PNG file
                end

                % Set the app's ImageSource to the image
                app.ImageInputConvolution.ImageSource = img_path;  % ImageSource must be a supported format
            end
        end

        % Value changed function: SpinnerConvolution
        function SpinnerConvolutionValueChanged(app, event)
            % Get the current spinner value
            value = app.SpinnerConvolution.Value;
            
            % Create a matrix of zeros with size (value x value)
            newData = zeros(value, value);
            
            % Update the UITableConvolution with the new data
            app.UITableConvolution.Data = newData;
        end

        % Button pushed function: ExecuteConvolutionButton
        function ExecuteConvolutionButtonPushed(app, event)
            % Retrieve the image path from ImageInputConvolution
            imagePath = app.ImageInputConvolution.ImageSource;
            
            % Check if an image is loaded
            if isempty(imagePath)
                uialert(app.UIFigure, 'Please load an image first.', 'Error');
                return;
            end
            
            % Read the image file into a matrix
            imageMatrix = imread(imagePath);
            
            % Ensure the image is of valid type (e.g., convert double to uint8 if needed)
            if ~isa(imageMatrix, 'uint8')
                imageMatrix = im2uint8(imageMatrix);
            end
            
            % Get the convolution mask from UITableConvolution
            mask = app.UITableConvolution.Data;
            
            % Ensure the mask is a valid square matrix
            [rows, cols] = size(mask);
            if rows ~= cols
                uialert(app.UIFigure, 'The convolution mask must be square (e.g., 3x3, 5x5).', 'Error');
                return;
            end
            
            % Apply the convolution function
            resultImage = convolution(imageMatrix, mask);
            
            % Convert resultImage to uint8 if necessary
            if ~isa(resultImage, 'uint8')
                resultImage = im2uint8(resultImage);
            end

             % Display the result in the ImageOutputConvolution component
            if size(resultImage, 3) == 1
                % If it's grayscale, convert to RGB for display
                resultImage = cat(3, resultImage, resultImage, resultImage);
            end
            
            app.ImageOutputConvolution.ImageSource = resultImage; % Correctly set the ImageSource
        end

        % Value changed function: FilterTypeDropDownSmoothing
        function FilterTypeDropDownSmoothingValueChanged(app, event)

        end

        % Callback function
        function ImageSmoothingTabButtonDown(app, event)

        end

        % Button pushed function: LoadImageSmoothingButton
        function LoadImageSmoothingButtonPushed(app, event)
            % Open file explorer to select an image
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tif;*.tiff', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp, *.tif, *.tiff)'});
            if isequal(file, 0)
                return;  % User cancelled the file selection
            else
                % Load the selected image
                img_path = fullfile(path, file);
                img = imread(img_path);  % Read the image
                [~, name, ext] = fileparts(img_path);  % Get the file extension
                
                % Convert unsupported formats (like bmp or tiff) to png
                if strcmpi(ext, '.bmp') || strcmpi(ext, '.tiff') || strcmp(ext, '.tif')
                    % Save the converted image to a valid directory (using tempdir)
                    new_img_path = fullfile(tempdir, [name, '.png']);  % Use tempdir for temporary PNG file
                    imwrite(img, new_img_path);  % Convert and save as PNG
                    img_path = new_img_path;  % Update the path to the new PNG file
                end

                % Set the app's ImageSource to the image
                app.ImageInputSmoothing.ImageSource = img_path;  % ImageSource must be a supported format
            end
        end

        % Button pushed function: ExecuteSmoothingButton
        function ExecuteSmoothingButtonPushed(app, event)
            % Retrieve the image path from ImageInputSmoothing
            imagePath = app.ImageInputSmoothing.ImageSource; 
            
            % Check if an image is loaded
            if isempty(imagePath)
                uialert(app.UIFigure, 'Please load an image first.', 'Error');
                return;
            end
            
            % Read the image file into a matrix
            imageMatrix = imread(imagePath);
            
            % Get the mask size (n) from the edit field
            n = app.MaskSizeEditFieldSmoothing.Value;
            sigma = app.SigmaValueEditFieldSmoothing.Value;
            
            % Ensure n is a positive odd integer
            if mod(n, 2) == 0 || n < 1
                uialert(app.UIFigure, 'Mask size must be a positive odd integer.', 'Error');
                return;
            end
            
            if strcmp(app.FilterTypeDropDownSmoothing.Value, 'Mean Filter')
                % Apply the mean filter
                meanFilteredImage = meanFilter(imageMatrix, n);
                
                % Display the mean filtered image in the output UI element
                if size(meanFilteredImage, 3) == 1
                    % If the result is grayscale, convert it to RGB
                    meanFilteredImage = cat(3, meanFilteredImage, meanFilteredImage, meanFilteredImage);
                end
                
                app.ImageOutputSmoothing.ImageSource = meanFilteredImage; % Assuming this is the output image component
            else
                % Apply the gaussian filter
                gaussianFilteredImage = gaussianFilter(imageMatrix, n, sigma);
                
                % Display the mean filtered image in the output UI element
                if size(gaussianFilteredImage, 3) == 1
                    % If the result is grayscale, convert it to RGB
                    gaussianFilteredImage = cat(3, gaussianFilteredImage, gaussianFilteredImage, gaussianFilteredImage);
                end
                
                app.ImageOutputSmoothing.ImageSource = gaussianFilteredImage; % Assuming this is the output image component
            end
        end

        % Button pushed function: LoadImageLPButton
        function LoadImageLPButtonPushed(app, event)
            % Open file explorer to select an image
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tif;*.tiff', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp, *.tif, *.tiff)'});
            if isequal(file, 0)
                return;  % User cancelled the file selection
            else
                % Load the selected image
                img_path = fullfile(path, file);
                img = imread(img_path);  % Read the image
                [~, name, ext] = fileparts(img_path);  % Get the file extension
                
                % Convert unsupported formats (like bmp or tiff) to png
                if strcmpi(ext, '.bmp') || strcmpi(ext, '.tiff') || strcmp(ext, '.tif')
                    % Save the converted image to a valid directory (using tempdir)
                    new_img_path = fullfile(tempdir, [name, '.png']);  % Use tempdir for temporary PNG file
                    imwrite(img, new_img_path);  % Convert and save as PNG
                    img_path = new_img_path;  % Update the path to the new PNG file
                end

                % Set the app's ImageSource to the image
                app.ImageInputLP.ImageSource = img_path;  % ImageSource must be a supported format
            end
        end

        % Button pushed function: ExecuteLPButton
        function ExecuteLPButtonPushed(app, event)
            % Retrieve the image path from ImageInputSmoothing
            imagePath = app.ImageInputLP.ImageSource; 
            
            % Check if an image is loaded
            if isempty(imagePath)
                uialert(app.UIFigure, 'Please load an image first.', 'Error');
                return;
            end
            
            % Read the image file into a matrix
            imageMatrix = imread(imagePath);
            
            % Get the mask size (n) from the edit field
            D0 = app.CutoffValueEditFieldLP.Value;
            n = app.OrderEditFieldLP.Value;
            
            dropdownValue = app.FilterTypeDropDownLP.Value;
            
            if strcmp(dropdownValue, 'ILPF')
                % Apply the ILPF filter
                resultImage = ILPF(imageMatrix, D0);
                
                % Display the mean filtered image in the output UI element
                if size(resultImage, 3) == 1
                    % If the result is grayscale, convert it to RGB
                    resultImage = cat(3, resultImage, resultImage, resultImage);
                end
                
                app.ImageOutputLP.ImageSource = resultImage;

            elseif strcmp(dropdownValue, 'GLPF')
                % Apply the GLPF filter
                resultImage = GLPF(imageMatrix, D0);
                
                % Display the mean filtered image in the output UI element
                if size(resultImage, 3) == 1
                    % If the result is grayscale, convert it to RGB
                    resultImage = cat(3, resultImage, resultImage, resultImage);
                end
                
                app.ImageOutputLP.ImageSource = resultImage;

            else
                % Apply the BLPF filter
                resultImage = BLPF(imageMatrix, D0, n);
                
                % Display the mean filtered image in the output UI element
                if size(resultImage, 3) == 1
                    % If the result is grayscale, convert it to RGB
                    resultImage = cat(3, resultImage, resultImage, resultImage);
                end
                
                app.ImageOutputLP.ImageSource = resultImage;
            end
        end

        % Button pushed function: LoadImageHPButton
        function LoadImageHPButtonPushed(app, event)
            % Open file explorer to select an image
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tif;*.tiff', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp, *.tif, *.tiff)'});
            if isequal(file, 0)
                return;  % User cancelled the file selection
            else
                % Load the selected image
                img_path = fullfile(path, file);
                img = imread(img_path);  % Read the image
                [~, name, ext] = fileparts(img_path);  % Get the file extension
                
                % Convert unsupported formats (like bmp or tiff) to png
                if strcmpi(ext, '.bmp') || strcmpi(ext, '.tiff') || strcmp(ext, '.tif')
                    % Save the converted image to a valid directory (using tempdir)
                    new_img_path = fullfile(tempdir, [name, '.png']);  % Use tempdir for temporary PNG file
                    imwrite(img, new_img_path);  % Convert and save as PNG
                    img_path = new_img_path;  % Update the path to the new PNG file
                end

                % Set the app's ImageSource to the image
                app.ImageInputHP.ImageSource = img_path;  % ImageSource must be a supported format
            end
        end

        % Button pushed function: ExecuteHPButton
        function ExecuteHPButtonPushed(app, event)
            % Retrieve the image path from ImageInputSmoothing
            imagePath = app.ImageInputHP.ImageSource; 
            
            % Check if an image is loaded
            if isempty(imagePath)
                uialert(app.UIFigure, 'Please load an image first.', 'Error');
                return;
            end
            
            % Read the image file into a matrix
            imageMatrix = imread(imagePath);
            
            % Get the mask size (n) from the edit field
            D0 = app.CutoffValueEditFieldHP.Value;
            n = app.OrderEditFieldHP.Value;
            
            dropdownValue = app.FilterTypeDropDownHP.Value;
            
            if strcmp(dropdownValue, 'IHPF')
                % Apply the IHPF filter
                resultImage = IHPF(imageMatrix, D0);
                
                % Display the mean filtered image in the output UI element
                if size(resultImage, 3) == 1
                    % If the result is grayscale, convert it to RGB
                    resultImage = cat(3, resultImage, resultImage, resultImage);
                end
                
                app.ImageOutputHP.ImageSource = resultImage;

            elseif strcmp(dropdownValue, 'GHPF')
                % Apply the GHPF filter
                resultImage = GHPF(imageMatrix, D0);
                
                % Display the mean filtered image in the output UI element
                if size(resultImage, 3) == 1
                    % If the result is grayscale, convert it to RGB
                    resultImage = cat(3, resultImage, resultImage, resultImage);
                end
                
                app.ImageOutputHP.ImageSource = resultImage;

            else
                % Apply the BHPF filter
                resultImage = BHPF(imageMatrix, D0, n);
                
                % Display the mean filtered image in the output UI element
                if size(resultImage, 3) == 1
                    % If the result is grayscale, convert it to RGB
                    resultImage = cat(3, resultImage, resultImage, resultImage);
                end
                
                app.ImageOutputHP.ImageSource = resultImage;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConvolutionTab = uitab(app.TabGroup);
            app.ConvolutionTab.Title = 'Convolution';
            app.ImageInputConvolution = uiimage(app.ConvolutionTab);
            app.UITableConvolution = uitable(app.ConvolutionTab);
            app.UITableConvolution.ColumnName = '';
            app.UITableConvolution.RowName = {};
            app.UITableConvolution.ColumnEditable = true;
            app.ImageOutputConvolution = uiimage(app.ConvolutionTab);
            app.InputImageConvolutionLabel = uilabel(app.ConvolutionTab);
            app.InputImageConvolutionLabel.Text = 'Input Image';
            app.OutputImageConvolutionLabel = uilabel(app.ConvolutionTab);
            app.OutputImageConvolutionLabel.Text = 'Output Image';
            app.MaskSizenxnSpinnerLabel = uilabel(app.ConvolutionTab);
            app.MaskSizenxnSpinnerLabel.Text = 'Mask Size (n x n)';
            app.SpinnerConvolution = uispinner(app.ConvolutionTab);
            app.SpinnerConvolution.Limits = [1 Inf];
            app.SpinnerConvolution.ValueChangedFcn = createCallbackFcn(app, @SpinnerConvolutionValueChanged, true);
            app.SpinnerConvolution.Value = 3;
            app.InputKernelConvolutionLabel = uilabel(app.ConvolutionTab);
            app.InputKernelConvolutionLabel.Text = 'Input Kernel';
            app.LoadImageConvolutionButton = uibutton(app.ConvolutionTab, 'push');
            app.LoadImageConvolutionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageConvolutionButtonPushed, true);
            app.LoadImageConvolutionButton.Text = 'Load Image';
            app.ExecuteConvolutionButton = uibutton(app.ConvolutionTab, 'push');
            app.ExecuteConvolutionButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteConvolutionButtonPushed, true);
            app.ExecuteConvolutionButton.Text = 'Execute';
            app.ImageSmoothingSpatialTab = uitab(app.TabGroup);
            app.ImageSmoothingSpatialTab.Title = 'Image Smoothing Spatial';
            app.ImageInputSmoothing = uiimage(app.ImageSmoothingSpatialTab);
            app.ImageOutputSmoothing = uiimage(app.ImageSmoothingSpatialTab);
            app.InputImageSmoothingLabel = uilabel(app.ImageSmoothingSpatialTab);
            app.InputImageSmoothingLabel.Text = 'Input Image';
            app.OutputImageSmoothingLabel = uilabel(app.ImageSmoothingSpatialTab);
            app.OutputImageSmoothingLabel.Text = 'Output Image';
            app.LoadImageSmoothingButton = uibutton(app.ImageSmoothingSpatialTab, 'push');
            app.LoadImageSmoothingButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageSmoothingButtonPushed, true);
            app.LoadImageSmoothingButton.Text = 'Load Image';
            app.ExecuteSmoothingButton = uibutton(app.ImageSmoothingSpatialTab, 'push');
            app.ExecuteSmoothingButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteSmoothingButtonPushed, true);
            app.ExecuteSmoothingButton.Text = 'Execute';
            app.FilterTypeDropDownLabel = uilabel(app.ImageSmoothingSpatialTab);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDownSmoothing = uidropdown(app.ImageSmoothingSpatialTab);
            app.FilterTypeDropDownSmoothing.Items = {'Mean Filter', 'Gaussian Filter'};
            app.FilterTypeDropDownSmoothing.ValueChangedFcn = createCallbackFcn(app, @FilterTypeDropDownSmoothingValueChanged, true);
            app.FilterTypeDropDownSmoothing.Value = 'Mean Filter';
            app.MaskSizeEditFieldLabel = uilabel(app.ImageSmoothingSpatialTab);
            app.MaskSizeEditFieldLabel.Text = 'Mask Size';
            app.MaskSizeEditFieldSmoothing = uieditfield(app.ImageSmoothingSpatialTab, 'numeric');
            app.MaskSizeEditFieldSmoothing.Limits = [1 Inf];
            app.MaskSizeEditFieldSmoothing.Value = 1;
            app.SigmaValueEditFieldLabel = uilabel(app.ImageSmoothingSpatialTab);
            app.SigmaValueEditFieldLabel.Text = 'Sigma Value';
            app.SigmaValueEditFieldSmoothing = uieditfield(app.ImageSmoothingSpatialTab, 'numeric');
            app.SigmaValueEditFieldSmoothing.Limits = [0 Inf];
            app.SigmaValueEditFieldSmoothing.Value = 1;
            app.ImageSmoothingFrequencyTab = uitab(app.TabGroup);
            app.ImageSmoothingFrequencyTab.Title = 'Image Smoothing Frequency';
            app.ImageInputLP = uiimage(app.ImageSmoothingFrequencyTab);
            app.ImageOutputLP = uiimage(app.ImageSmoothingFrequencyTab);
            app.InputImageLPLabel = uilabel(app.ImageSmoothingFrequencyTab);
            app.InputImageLPLabel.Text = 'Input Image';
            app.OutputImageLPLabel = uilabel(app.ImageSmoothingFrequencyTab);
            app.OutputImageLPLabel.Text = 'Output Image';
            app.LoadImageLPButton = uibutton(app.ImageSmoothingFrequencyTab, 'push');
            app.LoadImageLPButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageLPButtonPushed, true);
            app.LoadImageLPButton.Text = 'Load Image';
            app.ExecuteLPButton = uibutton(app.ImageSmoothingFrequencyTab, 'push');
            app.ExecuteLPButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteLPButtonPushed, true);
            app.ExecuteLPButton.Text = 'Execute';
            app.CutoffFrequencyLabel = uilabel(app.ImageSmoothingFrequencyTab);
            app.CutoffFrequencyLabel.Text = 'Cutoff Frequency';
            app.CutoffValueEditFieldLP = uieditfield(app.ImageSmoothingFrequencyTab, 'numeric');
            app.CutoffValueEditFieldLP.Limits = [0 Inf];
            app.CutoffValueEditFieldLP.Value = 1;
            app.OrderLabel = uilabel(app.ImageSmoothingFrequencyTab);
            app.OrderLabel.Text = 'Order';
            app.OrderEditFieldLP = uieditfield(app.ImageSmoothingFrequencyTab, 'numeric');
            app.OrderEditFieldLP.Limits = [0 Inf];
            app.OrderEditFieldLP.Value = 1;
            app.FilterTypeDropDownLabel_2 = uilabel(app.ImageSmoothingFrequencyTab);
            app.FilterTypeDropDownLabel_2.Text = 'Filter Type';
            app.FilterTypeDropDownLP = uidropdown(app.ImageSmoothingFrequencyTab);
            app.FilterTypeDropDownLP.Items = {'ILPF', 'GLPF', 'BLPF'};
            app.FilterTypeDropDownLP.Value = 'ILPF';
            app.ImageSharpeningFrequencyTab = uitab(app.TabGroup);
            app.ImageSharpeningFrequencyTab.Title = 'Image Sharpening Frequency';
            app.ImageInputHP = uiimage(app.ImageSharpeningFrequencyTab);
            app.ImageOutputHP = uiimage(app.ImageSharpeningFrequencyTab);
            app.InputImageHPLabel = uilabel(app.ImageSharpeningFrequencyTab);
            app.InputImageHPLabel.Text = 'Input Image';
            app.OutputImageHPLabel = uilabel(app.ImageSharpeningFrequencyTab);
            app.OutputImageHPLabel.Text = 'Output Image';
            app.LoadImageHPButton = uibutton(app.ImageSharpeningFrequencyTab, 'push');
            app.LoadImageHPButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageHPButtonPushed, true);
            app.LoadImageHPButton.Text = 'Load Image';
            app.ExecuteHPButton = uibutton(app.ImageSharpeningFrequencyTab, 'push');
            app.ExecuteHPButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteHPButtonPushed, true);
            app.ExecuteHPButton.Text = 'Execute';
            app.CutoffFrequencyLabel_2 = uilabel(app.ImageSharpeningFrequencyTab);
            app.CutoffFrequencyLabel_2.Text = 'Cutoff Frequency';
            app.CutoffValueEditFieldHP = uieditfield(app.ImageSharpeningFrequencyTab, 'numeric');
            app.CutoffValueEditFieldHP.Limits = [1 Inf];
            app.CutoffValueEditFieldHP.Value = 1;
            app.OrderLabel_2 = uilabel(app.ImageSharpeningFrequencyTab);
            app.OrderLabel_2.Text = 'Order';
            app.OrderEditFieldHP = uieditfield(app.ImageSharpeningFrequencyTab, 'numeric');
            app.OrderEditFieldHP.Limits = [0 Inf];
            app.OrderEditFieldHP.Value = 1;
            app.FilterTypeDropDownLabel_3 = uilabel(app.ImageSharpeningFrequencyTab);
            app.FilterTypeDropDownLabel_3.Text = 'Filter Type';
            app.FilterTypeDropDownHP = uidropdown(app.ImageSharpeningFrequencyTab);
            app.FilterTypeDropDownHP.Items = {'IHPF', 'GHPF', 'BHPF'};
            app.FilterTypeDropDownHP.Value = 'IHPF';
            app.ImageBrighteningTab = uitab(app.TabGroup);
            app.ImageBrighteningTab.Title = 'Image Brightening';
            app.SaltPepperRemovalTab = uitab(app.TabGroup);
            app.SaltPepperRemovalTab.Title = 'Salt & Pepper Removal';
            app.PeriodicNoiseRemovalTab = uitab(app.TabGroup);
            app.PeriodicNoiseRemovalTab.Title = 'Periodic Noise Removal';
            app.WienerMotionBlurTab = uitab(app.TabGroup);
            app.WienerMotionBlurTab.Title = 'Wiener Motion Blur';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end